classdef CityComparisons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        YourCityDropDownLabel       matlab.ui.control.Label
        YourCityDropDown            matlab.ui.control.DropDown
        CompareButton               matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        MoreaboutthecitiesLabel     matlab.ui.control.Label
        MoreaboutthecitiesTextArea  matlab.ui.control.TextArea
        UnemploymentratesofUScitiestocomparedtoyoursLabel  matlab.ui.control.Label
        USCityLabel_2               matlab.ui.control.Label
        USCityDropDown              matlab.ui.control.DropDown
        YourCityEditFieldLabel      matlab.ui.control.Label
        YourCityEditField           matlab.ui.control.EditField
        LegendLabel                 matlab.ui.control.Label
        USCityLabel                 matlab.ui.control.Label
        USCityEditField             matlab.ui.control.EditField
    end


    properties (Access = private)
        value;
        value2;
        value3;
        value4;
        value5;
        
    end


    methods (Access = private)

        % Button pushed function: CompareButton
        function compareButtonPushed(app, event)
       % An array is initialized so it can later be looped through and
       % compared to the values of the user's desired cities
   
            input = {'Anniston-Oxford-Jacksonville-AL', 'Auburn-Opelika-AL', 'Birmingham-Hoover-AL', 'Daphne-Fairhope-Foley-AL', 'Decatur-AL', 'Dothan-AL', 'Florence-Muscle Shoals-AL', 'Gadsden-AL', 'Huntsville-AL', 'Mobile-AL', 'Montgomery-AL', 'Tuscaloosa-AL', 'Anchorage-AK', 'Fairbanks-AK', 'Flagstaff-AZ', 'Lake Havasu City-Kingman-AZ', 'Phoenix-Mesa-Scottsdale-AZ', 'Prescott-AZ', 'Sierra Vista-Douglas-AZ', 'Tucson-AZ', 'Yuma-AZ', 'Fayetteville-Springdale-Rogers-AR-MO', 'Fort Smith-AR-OK', 'Hot Springs-AR', 'Jonesboro-AR', 'Little Rock-North Little Rock-Conway-AR', 'Pine Bluff-AR', 'Bakersfield-CA', 'Chico-CA', 'El Centro-CA', 'Fresno-CA', 'Hanford-Corcoran-CA', 'Los Angeles-Long Beach-Anaheim-CA', 'Madera-CA', 'Merced-CA', 'Modesto-CA', 'Napa-CA', 'Oxnard-Thousand Oaks-Ventura-CA', 'Redding-CA', 'Riverside-San Bernardino-Ontario-CA', 'Sacramento--Roseville--Arden-Arcade-CA', 'Salinas-CA', 'San Diego-Carlsbad-CA', 'San Francisco-Oakland-Hayward-CA', 'San Jose-Sunnyvale-Santa Clara-CA', 'San Luis Obispo-Paso Robles-Arroyo Grande-CA', 'Santa Cruz-Watsonville-CA', 'Santa Maria-Santa Barbara-CA', 'Santa Rosa-CA', 'Stockton-Lodi-CA', 'Vallejo-Fairfield-CA', 'Visalia-Porterville-CA', 'Yuba City-CA ', 'Boulder-CO', 'Colorado Springs-CO', 'Denver-Aurora-Lakewood-CO', 'Fort Collins-CO', 'Grand Junction-CO', 'Greeley-CO', 'Pueblo-CO', 'Bridgeport-Stamford-Norwalk-CT', 'Danbury-CT', 'Hartford-West Hartford-East Hartford-CT', 'New Haven-CT', 'Norwich-New London-Westerly-CT-RI', 'Waterbury-CT', 'Dover-DE', 'Salisbury-MD-DE', 'Washington-Arlington-Alexandria-DC-VA-MD-WV ', 'Cape Coral-Fort Myers-FL', 'Crestview-Fort Walton Beach-Destin-FL ', 'Deltona-Daytona Beach-Ormond Beach-FL ', 'Gainesville-FL', 'Homosassa Springs-FL', 'Jacksonville-FL', 'Lakeland-Winter Haven-FL', 'Miami-Fort Lauderdale-West Palm Beach-FL', 'Naples-Immokalee-Marco Island-FL', 'North Port-Sarasota-Bradenton-FL', 'Ocala-FL', 'Orlando-Kissimmee-Sanford-FL ', 'Palm Bay-Melbourne-Titusville-FL ', 'Panama City-FL ', 'Pensacola-Ferry Pass-Brent-FL ', 'Port St. Lucie-FL ', 'Punta Gorda-FL', 'Sebastian-Vero Beach-FL', 'Sebring-FL', 'Tallahassee-FL', 'Tampa-St. Petersburg-Clearwater-FL', 'The Villages-FL', 'Albany-GA', 'Athens-Clarke County-GA', 'Atlanta-Sandy Springs-Roswell-GA', 'Augusta-Richmond County-GA-SC', 'Brunswick-GA', 'Columbus-GA-AL', 'Dalton-GA ', 'Gainesville-GA', 'Hinesville-GA ', 'Macon-Bibb County-GA', 'Rome-GA ', 'Savannah-GA', 'Valdosta-GA ', 'Warner Robins-GA', 'Kahului-Wailuku-Lahaina-HI ', 'Urban Honolulu-HI', 'Boise City-ID', 'Coeur d''Alene-ID', 'Idaho Falls-ID', 'Lewiston-ID-WA', 'Pocatello-ID', 'Bloomington-IL', 'Carbondale-Marion-IL', 'Champaign-Urbana-IL', 'Chicago-Naperville-Elgin-IL-IN-WI', 'Danville-IL', 'Davenport-Moline-Rock Island-IA-IL', 'Decatur-IL', 'Kankakee-IL ', 'Peoria-IL ', 'Rockford-IL', 'Springfield-IL', 'Bloomington-IN', 'Columbus-IN', 'Elkhart-Goshen-IN', 'Evansville-IN-KY', 'Fort Wayne-IN', 'Indianapolis-Carmel-Anderson-IN', 'Kokomo-IN', 'Lafayette-West Lafayette-IN', 'Michigan City-La Porte-IN', 'Muncie-IN', 'South Bend-Mishawaka-IN-MI', 'Terre Haute-IN', 'Ames-IA ', 'Cedar Rapids-IA', 'Des Moines-West Des Moines-IA', 'Dubuque-IA', 'Iowa City-IA', 'Sioux City-IA-NE-SD', 'Waterloo-Cedar Falls-IA', 'Lawrence-KS ', 'Manhattan-KS', 'Topeka-KS', 'Wichita-KS ', 'Bowling Green-KY ', 'Elizabethtown-Fort Knox-KY', 'Lexington-Fayette-KY', 'Louisville/Jefferson County-KY-IN', 'Owensboro-KY', 'Alexandria-LA ', 'Baton Rouge-LA ', 'Hammond-LA', 'Houma-Thibodaux-LA ', 'Lafayette-LA ', 'Lake Charles-LA', 'Monroe-LA ', 'New Orleans-Metairie-LA ', 'Shreveport-Bossier City-LA ', 'Bangor-ME ', 'Lewiston-Auburn-ME ', 'Portland-South Portland-ME ', 'Baltimore-Columbia-Towson-MD ', 'California-Lexington Park-MD ', 'Cumberland-MD-WV ', 'Hagerstown-Martinsburg-MD-WV ', 'Barnstable Town-MA ', 'Boston-Cambridge-Nashua-MA-NH ', 'Leominster-Gardner-MA ', 'New Bedford-MA ', 'Pittsfield-MA ', 'Springfield-MA-CT ', 'Worcester-MA-CT ', 'Ann Arbor-MI', 'Battle Creek-MI', 'Bay City-MI', 'Detroit-Warren-Dearborn-MI', 'Flint-MI', 'Grand Rapids-Wyoming-MI', 'Jackson-MI', 'Kalamazoo-Portage-MI', 'Lansing-East Lansing-MI', 'Midland-MI', 'Monroe-MI', 'Muskegon-MI', 'Niles-Benton Harbor-MI', 'Saginaw-MI', 'Duluth-MN-WI ', 'Mankato-North Mankato-MN ', 'Minneapolis-St. Paul-Bloomington-MN-WI ', 'Rochester-MN ', 'St. Cloud-MN ', 'Gulfport-Biloxi-Pascagoula-MS ', 'Hattiesburg-MS ', 'Jackson-MS ', 'Cape Girardeau-MO-IL ', 'Columbia-MO ', 'Jefferson City-MO ', 'Joplin-MO ', 'Kansas City-MO-KS ', 'St. Joseph-MO-KS ', 'St. Louis-MO-IL ', 'Springfield-MO ', 'Billings-MT ', 'Great Falls-MT ', 'Missoula-MT ', 'Grand Island-NE ', 'Lincoln-NE ', 'Omaha-Council Bluffs-NE-IA ', 'Carson City-NV', 'Las Vegas-Henderson-Paradise-NV ', 'Reno-NV ', 'Dover-Durham-NH-ME  ', 'Manchester-NH  ', 'Portsmouth-NH-ME  ', 'Atlantic City-Hammonton-NJ ', 'Ocean City-NJ ', 'Trenton-NJ ', 'Vineland-Bridgeton-NJ ', 'Albuquerque-NM ', 'Farmington-NM ', 'Las Cruces-NM ', 'Santa Fe-NM ', 'Albany-Schenectady-Troy-NY ', 'Binghamton-NY ', 'Buffalo-Cheektowaga-Niagara Falls-NY ', 'Elmira-NY ', 'Glens Falls-NY ', 'Ithaca-NY ', 'Kingston-NY ', 'New York-Newark-Jersey City-NY-NJ-PA ', 'Rochester-NY ', 'Syracuse-NY ', 'Utica-Rome-NY ', 'Watertown-Fort Drum-NY ', 'Asheville-NC ', 'Burlington-NC ', 'Charlotte-Concord-Gastonia-NC-SC ', 'Durham-Chapel Hill-NC ', 'Fayetteville-NC ', 'Goldsboro-NC ', 'Greensboro-High Point-NC ', 'Greenville-NC ', 'Hickory-Lenoir-Morganton-NC ', 'Jacksonville-NC ', 'New Bern-NC ', 'Raleigh-NC ', 'Rocky Mount-NC ', 'Wilmington-NC ', 'Winston-Salem-NC ', 'Bismarck-ND ', 'Fargo-ND-MN ', 'Grand Forks-ND-MN ', 'Akron-OH ', 'Canton-Massillon-OH ', 'Cincinnati-OH-KY-IN ', 'Cleveland-Elyria-OH ', 'Columbus-OH ', 'Dayton-OH ', 'Lima-OH ', 'Mansfield-OH ', 'Springfield-OH ', 'Toledo-OH ', 'Weirton-Steubenville-WV-OH ', 'Youngstown-Warren-Boardman-OH-PA ', 'Enid-OK ', 'Lawton-OK ', 'Oklahoma City-OK ', 'Tulsa-OK ', 'Albany-OR ', 'Bend-Redmond-OR ', 'Corvallis-OR ', 'Eugene-OR ', 'Grants Pass-OR ', 'Medford-OR ', 'Portland-Vancouver-Hillsboro-OR-WA ', 'Salem-OR ', 'Allentown-Bethlehem-Easton-PA-NJ ', 'Altoona-PA ', 'Bloomsburg-Berwick-PA ', 'Chambersburg-Waynesboro-PA ', 'East Stroudsburg-PA ', 'Erie-PA ', 'Gettysburg-PA ', 'Harrisburg-Carlisle-PA ', 'Johnstown-PA ', 'Lancaster-PA ', 'Lebanon-PA ', 'Philadelphia-Camden-Wilmington-PA-NJ-DE-MD ', 'Pittsburgh-PA ', 'Reading-PA ', 'Scranton--Wilkes-Barre--Hazleton-PA ', 'State College-PA ', 'Williamsport-PA ', 'York-Hanover-PA ', 'Providence-Warwick-RI-MA  ', 'Charleston-North Charleston-SC ', 'Columbia-SC ', 'Florence-SC ', 'Greenville-Anderson-Mauldin-SC ', 'Hilton Head Island-Bluffton-Beaufort-SC ', 'Myrtle Beach-Conway-North Myrtle Beach-SC-NC ', 'Spartanburg-SC ', 'Sumter-SC ', 'Rapid City-SD ', 'Sioux Falls-SD ', 'Chattanooga-TN-GA ', 'Clarksville-TN-KY ', 'Cleveland-TN ', 'Jackson-TN ', 'Johnson City-TN ', 'Kingsport-Bristol-Bristol-TN-VA ', 'Knoxville-TN ', 'Memphis-TN-MS-AR ', 'Morristown-TN ', 'Nashville-Davidson--Murfreesboro--Franklin-TN ', 'Abilene-TX ', 'Amarillo-TX ', 'Austin-Round Rock-TX ', 'Beaumont-Port Arthur-TX ', 'Brownsville-Harlingen-TX ', 'College Station-Bryan-TX ', 'Corpus Christi-TX', 'Dallas-Fort Worth-Arlington-TX ', 'El Paso-TX', 'Houston-The Woodlands-Sugar Land-TX ', 'Killeen-Temple-TX ', 'Laredo-TX ', 'Longview-TX ', 'Lubbock-TX ', 'McAllen-Edinburg-Mission-TX ', 'Midland-TX ', 'Odessa-TX ', 'San Angelo-TX ', 'San Antonio-New Braunfels-TX ', 'Sherman-Denison-TX ', 'Texarkana-TX-AR ', 'Tyler-TX ', 'Victoria-TX ', 'Waco-TX ', 'Wichita Falls-TX ', 'Logan-UT-ID ', 'Ogden-Clearfield-UT ', 'Provo-Orem-UT ', 'St. George-UT ', 'Salt Lake City-UT ', 'Burlington-South Burlington-VT  ', 'Blacksburg-Christiansburg-Radford-VA ', 'Charlottesville-VA ', 'Harrisonburg-VA ', 'Lynchburg-VA ', 'Richmond-VA ', 'Roanoke-VA ', 'Staunton-Waynesboro-VA ', 'Virginia Beach-Norfolk-Newport News-VA-NC ', 'Winchester-VA-WV ', 'Bellingham-WA ', 'Bremerton-Silverdale-WA ', 'Kennewick-Richland-WA ', 'Longview-WA ', 'Mount Vernon-Anacortes-WA ', 'Olympia-Tumwater-WA ', 'Seattle-Tacoma-Bellevue-WA ', 'Spokane-Spokane Valley-WA ', 'Walla Walla-WA ', 'Wenatchee-WA ', 'Yakima-WA ', 'Beckley-WV ', 'Charleston-WV ', 'Huntington-Ashland-WV-KY-OH ', 'Morgantown-WV ', 'Parkersburg-Vienna-WV ', 'Wheeling-WV-OH ', 'Appleton-WI ', 'Eau Claire-WI ', 'Fond du Lac-WI ', 'Green Bay-WI ', 'Janesville-Beloit-WI ', 'La Crosse-Onalaska-WI-MN ', 'Madison-WI ', 'Milwaukee-Waukesha-West Allis-WI ', 'Oshkosh-Neenah-WI ', 'Racine-WI ', 'Sheboygan-WI ', 'Wausau-WI ', 'Casper-WY ', 'Cheyenne-WY ', 'Aguadilla-Isabela-PR ', 'Arecibo-PR ', 'Guayama-PR ', 'Mayaguez-PR ', 'Ponce-PR ', 'San German-PR ', 'San Juan-Carolina-Caguas-PR '};
            cinput = char(input);
            
       % The users selection of cities are recorded into variables  
       
            app.value = char(app.YourCityDropDown.Value);
            app.value2 = char(app.USCityDropDown.Value); 
            
       % Open file containing unemployment data for all the cities
       
           load citydata.mat
            
       % Initialize column vector to store unemployment rate data for the first month
       % of every year since 1990
       
            ratevec1 = zeros(29,1);
            ratevec2 = zeros(29,1);
            
       % Initialize column vector to store unemployment rate data for every month of 2018
       
            cvec1 = zeros(10,1);
            cvec2 = zeros(10,1);
            
            lf1 = 0;
            lf2 = 0;
            
    
       % For loop that checks if user value is equivilant to any input values
       % and assigns user selections a certain number based on location 
       % in input struct
       
       for i = 1 : 395
           if strcmpi(app.value,input{i})
               % assign values to first month unemployment rates of your city since 1990
               ratevec1 = UnemploymentData.URate(i+3:4740:end);
               % assign values to unemployment rates of every month in 2018 to your city
               cvec1 = UnemploymentData.URate(i+3+132720:395:136673);
               % store names of city 
               name1 = UnemploymentData.City(i+3);
               % Find number of people in labor force in october 2018
               lf1= UnemploymentData.LaborForce(i+3+136275);
           end
       end
           
       for i = 1 : 395
               
           if strcmpi(app.value2,input{i})
               % assign values to first month unemployment rates of US city since 1990
               ratevec2 = UnemploymentData.URate(i+3:4740:end);
               % assign values to unemployment rates of every month in 2018 to US city
               cvec2 = UnemploymentData.URate(i+3+132720:395:136673);
               % store names of city 
               name2 = UnemploymentData.City(i+3);
               % Find number of people in labor force in october 2018
               lf2= UnemploymentData.LaborForce(i+3+136275);
           end
       end
           
       % Places values in a data structure
  
            CityUR(2) = struct('Name', name2, 'URates', ratevec2, 'CurrentRate', cvec2, 'LaborForce', lf2);
            CityUR(1) = struct('Name', name1,'URates', ratevec1, 'CurrentRate', cvec1, 'LaborForce', lf1);
            
       % Analyze values to intialize a file that will be used to write to text box
      
        fid = fopen('CityAnalysis.rtf','w');

        %Point System To Determine Which City Is Best To Work
        C1pt=0;
        C2pt=0;

        %Gets Current Unemployment Rate
        C1C = CityUR(1).CurrentRate(10);
        C2C = CityUR(2).CurrentRate(10);

        %Gets Average From 1990-2018
        C1Avg = mean(CityUR(1).URates);
        C2Avg = mean(CityUR(2).URates);

        %Gets Average For 2018
        C1Avg18 = mean(CityUR(1).CurrentRate);
        C2Avg18 = mean(CityUR(2).CurrentRate);

        %Slope For Last 4 Months
        C1Slope = (CityUR(1).CurrentRate(10) - CityUR(1).CurrentRate(7))/4;
        C2Slope = (CityUR(2).CurrentRate(10) - CityUR(2).CurrentRate(7))/4;

        %Prediction For November
        C1Pre = (C1Slope * 5) + CityUR(1).CurrentRate(7);
        C2Pre = (C2Slope * 5) + CityUR(2).CurrentRate(7);

        %Writes Analysis Into A File
        fprintf(fid, 'The current labor force of %s is %d workers.\nThe current labor force of %s is %d workers\n', CityUR(1).Name, CityUR(1).LaborForce, CityUR(2).Name, CityUR(2).LaborForce);

        %Writes and compares labor force
        if CityUR(1).LaborForce > CityUR(2).LaborForce
            fprintf(fid, '%s has a larger labor force\n\n',CityUR(1).Name);
            C1pt= C1pt+1;
        elseif CityUR(1).LaborForce < CityUR(2).LaborForce
            fprintf(fid, '%s has a larger labor force\n\n',CityUR(2).Name);
            C2pt= C2pt+1;
        else 
            fprintf(fid, 'Both cities have the same amount of workers!\n\n');
        end

        %Writes and compares avg unemployment from 1990-2018
        fprintf(fid, 'From 1990-2018 the average unemployment rate of %s is %.2f%%.\nAnd the average unemployment rate of %s is %.2f%%\n', CityUR(1).Name, C1Avg, CityUR(2).Name, C2Avg);
        if C1Avg > C2Avg
            fprintf(fid, '%s historically has a smaller unemployment rate\n\n',CityUR(2).Name);
            C2pt= C2pt+1;
        elseif C1Avg < C2Avg
            fprintf(fid, '%s historically has a smaller unemployment rate\n\n',CityUR(1).Name);
            C1pt= C1pt+1;
        else 
            fprintf(fid, 'Both cities have the same average unemployment rates!\n\n');
        end

        %Writes the current unemployment rates
        fprintf(fid, 'The current unemployment rate of %s is %.2f%%.\nThe current unemployment rate of %s is %.2f%%.\n\n', CityUR(1).Name, C1C, CityUR(2).Name, C2C);
        if C1C > C2C
            C2pt= C2pt+1;
        elseif C1C < C2C
            C1pt= C1pt+1;
        else
        end

        %Writes the average unemployment rates
        fprintf(fid, 'The average unemployment rate of %s for 2018 is %.2f%%\nAnd the average unemployment rate of %s is %.2f%%.\n\n', CityUR(1).Name, C1Avg18, CityUR(2).Name, C2Avg18);
        if C1Avg18 > C2Avg18
            C2pt= C2pt+1;
        elseif C1Avg18 < C2Avg18
            C1pt= C1pt+1;
        else    
        end

        %Writes the trend of the unemployment rate for each city in 2018 in the last 4
        %months
        if C1Slope < 0
            fprintf(fid,'%s is at a downward trend for unemployment rate\n', CityUR(1).Name);
            C1pt= C1pt+1;
        elseif C1Slope > 0 
            fprintf(fid,'%s is unfortunately at an increasing trend for unemployment rate\n', CityUR(1).Name);
        else
            fprintf(fid,'%s is at a stagnant unemployment rate\n', CityUR(1).Name)
        end
        if C2Slope < 0
            fprintf(fid,'%s is at a downward trend for unemployment rate\n\n', CityUR(2).Name);
            C2pt= C2pt+1;
        elseif C2Slope > 0 
            fprintf(fid,'%s is unfortunately at an increasing trend for unemployment rate\n\n', CityUR(2).Name);
        else
            fprintf(fid,'%s is at a stagnant unemployment rate\n\n', CityUR(2).Name);
        end

        %Writes the predicted unemployment rate for November 2018 
        fprintf(fid, 'The predicted unemployment rate for %s in November 2018 is %.2f%%\n', CityUR(1).Name, C1Pre);
        fprintf(fid, 'The predicted unemployment rate for %s in November 2018 is %.2f%%\n\n\n', CityUR(2).Name, C2Pre);

        if C1Pre > C2Pre
            C2pt= C2pt+2;
        elseif C1Pre < C2Pre
            C1pt= C1pt+2;
        else
        end


        %Writes which city would provide best opportunity for work
        if C1pt > C2pt
            fprintf(fid, '%s would provide more opportunities for work!', CityUR(1).Name); 
        elseif C1pt < C2pt
            fprintf(fid, '%s would provide more opportunities for work!', CityUR(2).Name);
        else 
            fprintf(fid, 'Both %s and %s have their pros and cons for opportunites for work', CityUR(1).Name, CityUR(2).Name);
        end
        
        fclose('all');
        
        fid = fopen('CityAnalysis.rtf','r');
        line1 = fgets(fid);
        line2 = fgets(fid);
        line3 = fgets(fid);
        line4 = fgets(fid);
        line5 = fgets(fid);
        line6 = fgets(fid);
        line7 = fgets(fid);
        line8 = fgets(fid);
        line9 = fgets(fid);
        line10 = fgets(fid);
        line11 = fgets(fid);
        line12 = fgets(fid);
        line13 = fgets(fid);
        line14 = fgets(fid);
        line15 = fgets(fid);
        line16 = fgets(fid);
        line17 = fgets(fid);
        line18 = fgets(fid); 
        line19 = fgets(fid);
        line20 = fgets(fid);
        line21 = fgets(fid);
        line22 = fgets(fid);

        
        app.MoreaboutthecitiesTextArea.Value = sprintf('%s%s%s%s%s%s%s%s%s%s%s%s%s%s%s%s%s%s%s%s%s%s', line1,line2,line3,line4,line5,line6,line7,line8,line9,line10,line11,line12,line13,line14,line15,line16,line17,line18,line19,line20,line21,line22);
        
        fclose('all');


       % Plot unemployment trends for first month of both cities since 1990 in UIAxes
       
            x = 1990:2018;
            y1 = CityUR(1).URates;
            y2 = CityUR(2).URates;

            
            line1 = plot(app.UIAxes,x,y1);
            line1.Color = [0 0 1];
            line1.LineWidth = 2;

            hold(app.UIAxes,'on');

            line2 = plot(app.UIAxes,x,y2);
            line2.Color = [1 0 0];
            line2.LineWidth = 2;

            xlabel(app.UIAxes,'Years')
            ylabel(app.UIAxes,'Unemployment Rate (%)')
            
            
            hold(app.UIAxes,'off');
       
       % Plot unemployment trends for 2018 of both cities in UIAxes2 
       
            y1 = CityUR(1).CurrentRate;
            y2 = CityUR(2).CurrentRate;

            
            b = bar(app.UIAxes2,[y1,y2], 'grouped');

            month = {'JAN ' , 'FEB ', 'MAR ' , 'APR ' , 'MAY ' , 'JUN ', 'JUL ' , 'AUG ', 'SEP ', 'OCT '};
            set(app.UIAxes2,'xtick',[1:10],'xticklabel',month);


            xlabel(app.UIAxes2,'month')
            ylabel(app.UIAxes2,'Unemployment Rate (%)')
            
            
        % Name the cities in the legend
        app.YourCityEditFieldLabel.Text = sprintf('%s',CityUR(1).Name);
        app.USCityLabel.Text = sprintf('%s', CityUR(2).Name);


        end

        % Value changed function: YourCityDropDown
        function YourCityDropDownValueChanged(app, event)
            app.value = app.YourCityDropDown.Value;
            
        end

        % Value changed function: USCityDropDown
        function USCityDropDownValueChanged(app, event)
            app.value2 = app.USCityDropDown.Value;
            
        end

        % Value changed function: MoreaboutthecitiesTextArea
        function MoreaboutthecitiesTextAreaValueChanged(app, event)
            app.value3 = app.MoreaboutthecitiesTextArea.Value;
            
        end

        % Value changed function: YourCityEditField
        function YourCityEditFieldValueChanged(app, event)
            app.value4 = app.YourCityEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.YourCityDropDownLabel = uilabel(app.UIFigure);
            app.YourCityDropDownLabel.Text = 'Your City:';
            app.YourCityDropDown = uidropdown(app.UIFigure);
            app.YourCityDropDown.Items = {'Anniston-Oxford-Jacksonville-AL', 'Auburn-Opelika-AL', 'Birmingham-Hoover-AL', 'Daphne-Fairhope-Foley-AL', 'Decatur-AL', 'Dothan-AL', 'Florence-Muscle Shoals-AL', 'Gadsden-AL', 'Huntsville-AL', 'Mobile-AL', 'Montgomery-AL', 'Tuscaloosa-AL', 'Anchorage-AK', 'Fairbanks-AK', 'Flagstaff-AZ', 'Lake Havasu City-Kingman-AZ', 'Phoenix-Mesa-Scottsdale-AZ', 'Prescott-AZ', 'Sierra Vista-Douglas-AZ', 'Tucson-AZ', 'Yuma-AZ', 'Fayetteville-Springdale-Rogers-AR-MO', 'Fort Smith-AR-OK', 'Hot Springs-AR', 'Jonesboro-AR', 'Little Rock-North Little Rock-Conway-AR', 'Pine Bluff-AR', 'Bakersfield-CA', 'Chico-CA', 'El Centro-CA', 'Fresno-CA', 'Hanford-Corcoran-CA', 'Los Angeles-Long Beach-Anaheim-CA', 'Madera-CA', 'Merced-CA', 'Modesto-CA', 'Napa-CA', 'Oxnard-Thousand Oaks-Ventura-CA', 'Redding-CA', 'Riverside-San Bernardino-Ontario-CA', 'Sacramento--Roseville--Arden-Arcade-CA', 'Salinas-CA', 'San Diego-Carlsbad-CA', 'San Francisco-Oakland-Hayward-CA', 'San Jose-Sunnyvale-Santa Clara-CA', 'San Luis Obispo-Paso Robles-Arroyo Grande-CA', 'Santa Cruz-Watsonville-CA', 'Santa Maria-Santa Barbara-CA', 'Santa Rosa-CA', 'Stockton-Lodi-CA', 'Vallejo-Fairfield-CA', 'Visalia-Porterville-CA', 'Yuba City-CA ', 'Boulder-CO', 'Colorado Springs-CO', 'Denver-Aurora-Lakewood-CO', 'Fort Collins-CO', 'Grand Junction-CO', 'Greeley-CO', 'Pueblo-CO', 'Bridgeport-Stamford-Norwalk-CT', 'Danbury-CT', 'Hartford-West Hartford-East Hartford-CT', 'New Haven-CT', 'Norwich-New London-Westerly-CT-RI', 'Waterbury-CT', 'Dover-DE', 'Salisbury-MD-DE', 'Washington-Arlington-Alexandria-DC-VA-MD-WV ', 'Cape Coral-Fort Myers-FL', 'Crestview-Fort Walton Beach-Destin-FL ', 'Deltona-Daytona Beach-Ormond Beach-FL ', 'Gainesville-FL', 'Homosassa Springs-FL', 'Jacksonville-FL', 'Lakeland-Winter Haven-FL', 'Miami-Fort Lauderdale-West Palm Beach-FL', 'Naples-Immokalee-Marco Island-FL', 'North Port-Sarasota-Bradenton-FL', 'Ocala-FL', 'Orlando-Kissimmee-Sanford-FL ', 'Palm Bay-Melbourne-Titusville-FL ', 'Panama City-FL ', 'Pensacola-Ferry Pass-Brent-FL ', 'Port St. Lucie-FL ', 'Punta Gorda-FL', 'Sebastian-Vero Beach-FL', 'Sebring-FL', 'Tallahassee-FL', 'Tampa-St. Petersburg-Clearwater-FL', 'The Villages-FL', 'Albany-GA', 'Athens-Clarke County-GA', 'Atlanta-Sandy Springs-Roswell-GA', 'Augusta-Richmond County-GA-SC', 'Brunswick-GA', 'Columbus-GA-AL', 'Dalton-GA ', 'Gainesville-GA', 'Hinesville-GA ', 'Macon-Bibb County-GA', 'Rome-GA ', 'Savannah-GA', 'Valdosta-GA ', 'Warner Robins-GA', 'Kahului-Wailuku-Lahaina-HI ', 'Urban Honolulu-HI', 'Boise City-ID', 'Coeur d''Alene-ID', 'Idaho Falls-ID', 'Lewiston-ID-WA', 'Pocatello-ID', 'Bloomington-IL', 'Carbondale-Marion-IL', 'Champaign-Urbana-IL', 'Chicago-Naperville-Elgin-IL-IN-WI', 'Danville-IL', 'Davenport-Moline-Rock Island-IA-IL', 'Decatur-IL', 'Kankakee-IL ', 'Peoria-IL ', 'Rockford-IL', 'Springfield-IL', 'Bloomington-IN', 'Columbus-IN', 'Elkhart-Goshen-IN', 'Evansville-IN-KY', 'Fort Wayne-IN', 'Indianapolis-Carmel-Anderson-IN', 'Kokomo-IN', 'Lafayette-West Lafayette-IN', 'Michigan City-La Porte-IN', 'Muncie-IN', 'South Bend-Mishawaka-IN-MI', 'Terre Haute-IN', 'Ames-IA ', 'Cedar Rapids-IA', 'Des Moines-West Des Moines-IA', 'Dubuque-IA', 'Iowa City-IA', 'Sioux City-IA-NE-SD', 'Waterloo-Cedar Falls-IA', 'Lawrence-KS ', 'Manhattan-KS', 'Topeka-KS', 'Wichita-KS ', 'Bowling Green-KY ', 'Elizabethtown-Fort Knox-KY', 'Lexington-Fayette-KY', 'Louisville/Jefferson County-KY-IN', 'Owensboro-KY', 'Alexandria-LA ', 'Baton Rouge-LA ', 'Hammond-LA', 'Houma-Thibodaux-LA ', 'Lafayette-LA ', 'Lake Charles-LA', 'Monroe-LA ', 'New Orleans-Metairie-LA ', 'Shreveport-Bossier City-LA ', 'Bangor-ME ', 'Lewiston-Auburn-ME ', 'Portland-South Portland-ME ', 'Baltimore-Columbia-Towson-MD ', 'California-Lexington Park-MD ', 'Cumberland-MD-WV ', 'Hagerstown-Martinsburg-MD-WV ', 'Barnstable Town-MA ', 'Boston-Cambridge-Nashua-MA-NH ', 'Leominster-Gardner-MA ', 'New Bedford-MA ', 'Pittsfield-MA ', 'Springfield-MA-CT ', 'Worcester-MA-CT ', 'Ann Arbor-MI', 'Battle Creek-MI', 'Bay City-MI', 'Detroit-Warren-Dearborn-MI', 'Flint-MI', 'Grand Rapids-Wyoming-MI', 'Jackson-MI', 'Kalamazoo-Portage-MI', 'Lansing-East Lansing-MI', 'Midland-MI', 'Monroe-MI', 'Muskegon-MI', 'Niles-Benton Harbor-MI', 'Saginaw-MI', 'Duluth-MN-WI ', 'Mankato-North Mankato-MN ', 'Minneapolis-St. Paul-Bloomington-MN-WI ', 'Rochester-MN ', 'St. Cloud-MN ', 'Gulfport-Biloxi-Pascagoula-MS ', 'Hattiesburg-MS ', 'Jackson-MS ', 'Cape Girardeau-MO-IL ', 'Columbia-MO ', 'Jefferson City-MO ', 'Joplin-MO ', 'Kansas City-MO-KS ', 'St. Joseph-MO-KS ', 'St. Louis-MO-IL ', 'Springfield-MO ', 'Billings-MT ', 'Great Falls-MT ', 'Missoula-MT ', 'Grand Island-NE ', 'Lincoln-NE ', 'Omaha-Council Bluffs-NE-IA ', 'Carson City-NV', 'Las Vegas-Henderson-Paradise-NV ', 'Reno-NV ', 'Dover-Durham-NH-ME  ', 'Manchester-NH  ', 'Portsmouth-NH-ME  ', 'Atlantic City-Hammonton-NJ ', 'Ocean City-NJ ', 'Trenton-NJ ', 'Vineland-Bridgeton-NJ ', 'Albuquerque-NM ', 'Farmington-NM ', 'Las Cruces-NM ', 'Santa Fe-NM ', 'Albany-Schenectady-Troy-NY ', 'Binghamton-NY ', 'Buffalo-Cheektowaga-Niagara Falls-NY ', 'Elmira-NY ', 'Glens Falls-NY ', 'Ithaca-NY ', 'Kingston-NY ', 'New York-Newark-Jersey City-NY-NJ-PA ', 'Rochester-NY ', 'Syracuse-NY ', 'Utica-Rome-NY ', 'Watertown-Fort Drum-NY ', 'Asheville-NC ', 'Burlington-NC ', 'Charlotte-Concord-Gastonia-NC-SC ', 'Durham-Chapel Hill-NC ', 'Fayetteville-NC ', 'Goldsboro-NC ', 'Greensboro-High Point-NC ', 'Greenville-NC ', 'Hickory-Lenoir-Morganton-NC ', 'Jacksonville-NC ', 'New Bern-NC ', 'Raleigh-NC ', 'Rocky Mount-NC ', 'Wilmington-NC ', 'Winston-Salem-NC ', 'Bismarck-ND ', 'Fargo-ND-MN ', 'Grand Forks-ND-MN ', 'Akron-OH ', 'Canton-Massillon-OH ', 'Cincinnati-OH-KY-IN ', 'Cleveland-Elyria-OH ', 'Columbus-OH ', 'Dayton-OH ', 'Lima-OH ', 'Mansfield-OH ', 'Springfield-OH ', 'Toledo-OH ', 'Weirton-Steubenville-WV-OH ', 'Youngstown-Warren-Boardman-OH-PA ', 'Enid-OK ', 'Lawton-OK ', 'Oklahoma City-OK ', 'Tulsa-OK ', 'Albany-OR ', 'Bend-Redmond-OR ', 'Corvallis-OR ', 'Eugene-OR ', 'Grants Pass-OR ', 'Medford-OR ', 'Portland-Vancouver-Hillsboro-OR-WA ', 'Salem-OR ', 'Allentown-Bethlehem-Easton-PA-NJ ', 'Altoona-PA ', 'Bloomsburg-Berwick-PA ', 'Chambersburg-Waynesboro-PA ', 'East Stroudsburg-PA ', 'Erie-PA ', 'Gettysburg-PA ', 'Harrisburg-Carlisle-PA ', 'Johnstown-PA ', 'Lancaster-PA ', 'Lebanon-PA ', 'Philadelphia-Camden-Wilmington-PA-NJ-DE-MD ', 'Pittsburgh-PA ', 'Reading-PA ', 'Scranton--Wilkes-Barre--Hazleton-PA ', 'State College-PA ', 'Williamsport-PA ', 'York-Hanover-PA ', 'Providence-Warwick-RI-MA  ', 'Charleston-North Charleston-SC ', 'Columbia-SC ', 'Florence-SC ', 'Greenville-Anderson-Mauldin-SC ', 'Hilton Head Island-Bluffton-Beaufort-SC ', 'Myrtle Beach-Conway-North Myrtle Beach-SC-NC ', 'Spartanburg-SC ', 'Sumter-SC ', 'Rapid City-SD ', 'Sioux Falls-SD ', 'Chattanooga-TN-GA ', 'Clarksville-TN-KY ', 'Cleveland-TN ', 'Jackson-TN ', 'Johnson City-TN ', 'Kingsport-Bristol-Bristol-TN-VA ', 'Knoxville-TN ', 'Memphis-TN-MS-AR ', 'Morristown-TN ', 'Nashville-Davidson--Murfreesboro--Franklin-TN ', 'Abilene-TX ', 'Amarillo-TX ', 'Austin-Round Rock-TX ', 'Beaumont-Port Arthur-TX ', 'Brownsville-Harlingen-TX ', 'College Station-Bryan-TX ', 'Corpus Christi-TX', 'Dallas-Fort Worth-Arlington-TX ', 'El Paso-TX', 'Houston-The Woodlands-Sugar Land-TX ', 'Killeen-Temple-TX ', 'Laredo-TX ', 'Longview-TX ', 'Lubbock-TX ', 'McAllen-Edinburg-Mission-TX ', 'Midland-TX ', 'Odessa-TX ', 'San Angelo-TX ', 'San Antonio-New Braunfels-TX ', 'Sherman-Denison-TX ', 'Texarkana-TX-AR ', 'Tyler-TX ', 'Victoria-TX ', 'Waco-TX ', 'Wichita Falls-TX ', 'Logan-UT-ID ', 'Ogden-Clearfield-UT ', 'Provo-Orem-UT ', 'St. George-UT ', 'Salt Lake City-UT ', 'Burlington-South Burlington-VT  ', 'Blacksburg-Christiansburg-Radford-VA ', 'Charlottesville-VA ', 'Harrisonburg-VA ', 'Lynchburg-VA ', 'Richmond-VA ', 'Roanoke-VA ', 'Staunton-Waynesboro-VA ', 'Virginia Beach-Norfolk-Newport News-VA-NC ', 'Winchester-VA-WV ', 'Bellingham-WA ', 'Bremerton-Silverdale-WA ', 'Kennewick-Richland-WA ', 'Longview-WA ', 'Mount Vernon-Anacortes-WA ', 'Olympia-Tumwater-WA ', 'Seattle-Tacoma-Bellevue-WA ', 'Spokane-Spokane Valley-WA ', 'Walla Walla-WA ', 'Wenatchee-WA ', 'Yakima-WA ', 'Beckley-WV ', 'Charleston-WV ', 'Huntington-Ashland-WV-KY-OH ', 'Morgantown-WV ', 'Parkersburg-Vienna-WV ', 'Wheeling-WV-OH ', 'Appleton-WI ', 'Eau Claire-WI ', 'Fond du Lac-WI ', 'Green Bay-WI ', 'Janesville-Beloit-WI ', 'La Crosse-Onalaska-WI-MN ', 'Madison-WI ', 'Milwaukee-Waukesha-West Allis-WI ', 'Oshkosh-Neenah-WI ', 'Racine-WI ', 'Sheboygan-WI ', 'Wausau-WI ', 'Casper-WY ', 'Cheyenne-WY ', 'Aguadilla-Isabela-PR ', 'Arecibo-PR ', 'Guayama-PR ', 'Mayaguez-PR ', 'Ponce-PR ', 'San German-PR ', 'San Juan-Carolina-Caguas-PR'};
            app.YourCityDropDown.ValueChangedFcn = createCallbackFcn(app, @YourCityDropDownValueChanged, true);
            app.YourCityDropDown.Value = 'Anniston-Oxford-Jacksonville-AL';
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @compareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Unemploymemt Rates over time')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Unemployment Rate')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Unemployment trends 2018')
            xlabel(app.UIAxes2, 'Month')
            ylabel(app.UIAxes2, 'Unemployment Rate')
            app.MoreaboutthecitiesLabel = uilabel(app.UIFigure);
            app.MoreaboutthecitiesLabel.Text = 'More about the cities:';
            app.MoreaboutthecitiesTextArea = uitextarea(app.UIFigure);
            app.MoreaboutthecitiesTextArea.ValueChangedFcn = createCallbackFcn(app, @MoreaboutthecitiesTextAreaValueChanged, true);
            app.UnemploymentratesofUScitiestocomparedtoyoursLabel = uilabel(app.UIFigure);
            app.UnemploymentratesofUScitiestocomparedtoyoursLabel.Text = 'Unemployment rates of US cities to compared to yours';
            app.USCityLabel_2 = uilabel(app.UIFigure);
            app.USCityLabel_2.Text = 'US City:';
            app.USCityDropDown = uidropdown(app.UIFigure);
            app.USCityDropDown.Items = {'Anniston-Oxford-Jacksonville-AL', 'Auburn-Opelika-AL', 'Birmingham-Hoover-AL', 'Daphne-Fairhope-Foley-AL', 'Decatur-AL', 'Dothan-AL', 'Florence-Muscle Shoals-AL', 'Gadsden-AL', 'Huntsville-AL', 'Mobile-AL', 'Montgomery-AL', 'Tuscaloosa-AL', 'Anchorage-AK', 'Fairbanks-AK', 'Flagstaff-AZ', 'Lake Havasu City-Kingman-AZ', 'Phoenix-Mesa-Scottsdale-AZ', 'Prescott-AZ', 'Sierra Vista-Douglas-AZ', 'Tucson-AZ', 'Yuma-AZ', 'Fayetteville-Springdale-Rogers-AR-MO', 'Fort Smith-AR-OK', 'Hot Springs-AR', 'Jonesboro-AR', 'Little Rock-North Little Rock-Conway-AR', 'Pine Bluff-AR', 'Bakersfield-CA', 'Chico-CA', 'El Centro-CA', 'Fresno-CA', 'Hanford-Corcoran-CA', 'Los Angeles-Long Beach-Anaheim-CA', 'Madera-CA', 'Merced-CA', 'Modesto-CA', 'Napa-CA', 'Oxnard-Thousand Oaks-Ventura-CA', 'Redding-CA', 'Riverside-San Bernardino-Ontario-CA', 'Sacramento--Roseville--Arden-Arcade-CA', 'Salinas-CA', 'San Diego-Carlsbad-CA', 'San Francisco-Oakland-Hayward-CA', 'San Jose-Sunnyvale-Santa Clara-CA', 'San Luis Obispo-Paso Robles-Arroyo Grande-CA', 'Santa Cruz-Watsonville-CA', 'Santa Maria-Santa Barbara-CA', 'Santa Rosa-CA', 'Stockton-Lodi-CA', 'Vallejo-Fairfield-CA', 'Visalia-Porterville-CA', 'Yuba City-CA ', 'Boulder-CO', 'Colorado Springs-CO', 'Denver-Aurora-Lakewood-CO', 'Fort Collins-CO', 'Grand Junction-CO', 'Greeley-CO', 'Pueblo-CO', 'Bridgeport-Stamford-Norwalk-CT', 'Danbury-CT', 'Hartford-West Hartford-East Hartford-CT', 'New Haven-CT', 'Norwich-New London-Westerly-CT-RI', 'Waterbury-CT', 'Dover-DE', 'Salisbury-MD-DE', 'Washington-Arlington-Alexandria-DC-VA-MD-WV ', 'Cape Coral-Fort Myers-FL', 'Crestview-Fort Walton Beach-Destin-FL ', 'Deltona-Daytona Beach-Ormond Beach-FL ', 'Gainesville-FL', 'Homosassa Springs-FL', 'Jacksonville-FL', 'Lakeland-Winter Haven-FL', 'Miami-Fort Lauderdale-West Palm Beach-FL', 'Naples-Immokalee-Marco Island-FL', 'North Port-Sarasota-Bradenton-FL', 'Ocala-FL', 'Orlando-Kissimmee-Sanford-FL ', 'Palm Bay-Melbourne-Titusville-FL ', 'Panama City-FL ', 'Pensacola-Ferry Pass-Brent-FL ', 'Port St. Lucie-FL ', 'Punta Gorda-FL', 'Sebastian-Vero Beach-FL', 'Sebring-FL', 'Tallahassee-FL', 'Tampa-St. Petersburg-Clearwater-FL', 'The Villages-FL', 'Albany-GA', 'Athens-Clarke County-GA', 'Atlanta-Sandy Springs-Roswell-GA', 'Augusta-Richmond County-GA-SC', 'Brunswick-GA', 'Columbus-GA-AL', 'Dalton-GA ', 'Gainesville-GA', 'Hinesville-GA ', 'Macon-Bibb County-GA', 'Rome-GA ', 'Savannah-GA', 'Valdosta-GA ', 'Warner Robins-GA', 'Kahului-Wailuku-Lahaina-HI ', 'Urban Honolulu-HI', 'Boise City-ID', 'Coeur d''Alene-ID', 'Idaho Falls-ID', 'Lewiston-ID-WA', 'Pocatello-ID', 'Bloomington-IL', 'Carbondale-Marion-IL', 'Champaign-Urbana-IL', 'Chicago-Naperville-Elgin-IL-IN-WI', 'Danville-IL', 'Davenport-Moline-Rock Island-IA-IL', 'Decatur-IL', 'Kankakee-IL ', 'Peoria-IL ', 'Rockford-IL', 'Springfield-IL', 'Bloomington-IN', 'Columbus-IN', 'Elkhart-Goshen-IN', 'Evansville-IN-KY', 'Fort Wayne-IN', 'Indianapolis-Carmel-Anderson-IN', 'Kokomo-IN', 'Lafayette-West Lafayette-IN', 'Michigan City-La Porte-IN', 'Muncie-IN', 'South Bend-Mishawaka-IN-MI', 'Terre Haute-IN', 'Ames-IA ', 'Cedar Rapids-IA', 'Des Moines-West Des Moines-IA', 'Dubuque-IA', 'Iowa City-IA', 'Sioux City-IA-NE-SD', 'Waterloo-Cedar Falls-IA', 'Lawrence-KS ', 'Manhattan-KS', 'Topeka-KS', 'Wichita-KS ', 'Bowling Green-KY ', 'Elizabethtown-Fort Knox-KY', 'Lexington-Fayette-KY', 'Louisville/Jefferson County-KY-IN', 'Owensboro-KY', 'Alexandria-LA ', 'Baton Rouge-LA ', 'Hammond-LA', 'Houma-Thibodaux-LA ', 'Lafayette-LA ', 'Lake Charles-LA', 'Monroe-LA ', 'New Orleans-Metairie-LA ', 'Shreveport-Bossier City-LA ', 'Bangor-ME ', 'Lewiston-Auburn-ME ', 'Portland-South Portland-ME ', 'Baltimore-Columbia-Towson-MD ', 'California-Lexington Park-MD ', 'Cumberland-MD-WV ', 'Hagerstown-Martinsburg-MD-WV ', 'Barnstable Town-MA ', 'Boston-Cambridge-Nashua-MA-NH ', 'Leominster-Gardner-MA ', 'New Bedford-MA ', 'Pittsfield-MA ', 'Springfield-MA-CT ', 'Worcester-MA-CT ', 'Ann Arbor-MI', 'Battle Creek-MI', 'Bay City-MI', 'Detroit-Warren-Dearborn-MI', 'Flint-MI', 'Grand Rapids-Wyoming-MI', 'Jackson-MI', 'Kalamazoo-Portage-MI', 'Lansing-East Lansing-MI', 'Midland-MI', 'Monroe-MI', 'Muskegon-MI', 'Niles-Benton Harbor-MI', 'Saginaw-MI', 'Duluth-MN-WI ', 'Mankato-North Mankato-MN ', 'Minneapolis-St. Paul-Bloomington-MN-WI ', 'Rochester-MN ', 'St. Cloud-MN ', 'Gulfport-Biloxi-Pascagoula-MS ', 'Hattiesburg-MS ', 'Jackson-MS ', 'Cape Girardeau-MO-IL ', 'Columbia-MO ', 'Jefferson City-MO ', 'Joplin-MO ', 'Kansas City-MO-KS ', 'St. Joseph-MO-KS ', 'St. Louis-MO-IL ', 'Springfield-MO ', 'Billings-MT ', 'Great Falls-MT ', 'Missoula-MT ', 'Grand Island-NE ', 'Lincoln-NE ', 'Omaha-Council Bluffs-NE-IA ', 'Carson City-NV', 'Las Vegas-Henderson-Paradise-NV ', 'Reno-NV ', 'Dover-Durham-NH-ME  ', 'Manchester-NH  ', 'Portsmouth-NH-ME  ', 'Atlantic City-Hammonton-NJ ', 'Ocean City-NJ ', 'Trenton-NJ ', 'Vineland-Bridgeton-NJ ', 'Albuquerque-NM ', 'Farmington-NM ', 'Las Cruces-NM ', 'Santa Fe-NM ', 'Albany-Schenectady-Troy-NY ', 'Binghamton-NY ', 'Buffalo-Cheektowaga-Niagara Falls-NY ', 'Elmira-NY ', 'Glens Falls-NY ', 'Ithaca-NY ', 'Kingston-NY ', 'New York-Newark-Jersey City-NY-NJ-PA ', 'Rochester-NY ', 'Syracuse-NY ', 'Utica-Rome-NY ', 'Watertown-Fort Drum-NY ', 'Asheville-NC ', 'Burlington-NC ', 'Charlotte-Concord-Gastonia-NC-SC ', 'Durham-Chapel Hill-NC ', 'Fayetteville-NC ', 'Goldsboro-NC ', 'Greensboro-High Point-NC ', 'Greenville-NC ', 'Hickory-Lenoir-Morganton-NC ', 'Jacksonville-NC ', 'New Bern-NC ', 'Raleigh-NC ', 'Rocky Mount-NC ', 'Wilmington-NC ', 'Winston-Salem-NC ', 'Bismarck-ND ', 'Fargo-ND-MN ', 'Grand Forks-ND-MN ', 'Akron-OH ', 'Canton-Massillon-OH ', 'Cincinnati-OH-KY-IN ', 'Cleveland-Elyria-OH ', 'Columbus-OH ', 'Dayton-OH ', 'Lima-OH ', 'Mansfield-OH ', 'Springfield-OH ', 'Toledo-OH ', 'Weirton-Steubenville-WV-OH ', 'Youngstown-Warren-Boardman-OH-PA ', 'Enid-OK ', 'Lawton-OK ', 'Oklahoma City-OK ', 'Tulsa-OK ', 'Albany-OR ', 'Bend-Redmond-OR ', 'Corvallis-OR ', 'Eugene-OR ', 'Grants Pass-OR ', 'Medford-OR ', 'Portland-Vancouver-Hillsboro-OR-WA ', 'Salem-OR ', 'Allentown-Bethlehem-Easton-PA-NJ ', 'Altoona-PA ', 'Bloomsburg-Berwick-PA ', 'Chambersburg-Waynesboro-PA ', 'East Stroudsburg-PA ', 'Erie-PA ', 'Gettysburg-PA ', 'Harrisburg-Carlisle-PA ', 'Johnstown-PA ', 'Lancaster-PA ', 'Lebanon-PA ', 'Philadelphia-Camden-Wilmington-PA-NJ-DE-MD ', 'Pittsburgh-PA ', 'Reading-PA ', 'Scranton--Wilkes-Barre--Hazleton-PA ', 'State College-PA ', 'Williamsport-PA ', 'York-Hanover-PA ', 'Providence-Warwick-RI-MA  ', 'Charleston-North Charleston-SC ', 'Columbia-SC ', 'Florence-SC ', 'Greenville-Anderson-Mauldin-SC ', 'Hilton Head Island-Bluffton-Beaufort-SC ', 'Myrtle Beach-Conway-North Myrtle Beach-SC-NC ', 'Spartanburg-SC ', 'Sumter-SC ', 'Rapid City-SD ', 'Sioux Falls-SD ', 'Chattanooga-TN-GA ', 'Clarksville-TN-KY ', 'Cleveland-TN ', 'Jackson-TN ', 'Johnson City-TN ', 'Kingsport-Bristol-Bristol-TN-VA ', 'Knoxville-TN ', 'Memphis-TN-MS-AR ', 'Morristown-TN ', 'Nashville-Davidson--Murfreesboro--Franklin-TN ', 'Abilene-TX ', 'Amarillo-TX ', 'Austin-Round Rock-TX ', 'Beaumont-Port Arthur-TX ', 'Brownsville-Harlingen-TX ', 'College Station-Bryan-TX ', 'Corpus Christi-TX', 'Dallas-Fort Worth-Arlington-TX ', 'El Paso-TX', 'Houston-The Woodlands-Sugar Land-TX ', 'Killeen-Temple-TX ', 'Laredo-TX ', 'Longview-TX ', 'Lubbock-TX ', 'McAllen-Edinburg-Mission-TX ', 'Midland-TX ', 'Odessa-TX ', 'San Angelo-TX ', 'San Antonio-New Braunfels-TX ', 'Sherman-Denison-TX ', 'Texarkana-TX-AR ', 'Tyler-TX ', 'Victoria-TX ', 'Waco-TX ', 'Wichita Falls-TX ', 'Logan-UT-ID ', 'Ogden-Clearfield-UT ', 'Provo-Orem-UT ', 'St. George-UT ', 'Salt Lake City-UT ', 'Burlington-South Burlington-VT  ', 'Blacksburg-Christiansburg-Radford-VA ', 'Charlottesville-VA ', 'Harrisonburg-VA ', 'Lynchburg-VA ', 'Richmond-VA ', 'Roanoke-VA ', 'Staunton-Waynesboro-VA ', 'Virginia Beach-Norfolk-Newport News-VA-NC ', 'Winchester-VA-WV ', 'Bellingham-WA ', 'Bremerton-Silverdale-WA ', 'Kennewick-Richland-WA ', 'Longview-WA ', 'Mount Vernon-Anacortes-WA ', 'Olympia-Tumwater-WA ', 'Seattle-Tacoma-Bellevue-WA ', 'Spokane-Spokane Valley-WA ', 'Walla Walla-WA ', 'Wenatchee-WA ', 'Yakima-WA ', 'Beckley-WV ', 'Charleston-WV ', 'Huntington-Ashland-WV-KY-OH ', 'Morgantown-WV ', 'Parkersburg-Vienna-WV ', 'Wheeling-WV-OH ', 'Appleton-WI ', 'Eau Claire-WI ', 'Fond du Lac-WI ', 'Green Bay-WI ', 'Janesville-Beloit-WI ', 'La Crosse-Onalaska-WI-MN ', 'Madison-WI ', 'Milwaukee-Waukesha-West Allis-WI ', 'Oshkosh-Neenah-WI ', 'Racine-WI ', 'Sheboygan-WI ', 'Wausau-WI ', 'Casper-WY ', 'Cheyenne-WY ', 'Aguadilla-Isabela-PR ', 'Arecibo-PR ', 'Guayama-PR ', 'Mayaguez-PR ', 'Ponce-PR ', 'San German-PR ', 'San Juan-Carolina-Caguas-PR'};
            app.USCityDropDown.ValueChangedFcn = createCallbackFcn(app, @USCityDropDownValueChanged, true);
            app.USCityDropDown.Value = 'Anniston-Oxford-Jacksonville-AL';
            app.YourCityEditFieldLabel = uilabel(app.UIFigure);
            app.YourCityEditFieldLabel.Text = 'Your City:';
            app.YourCityEditField = uieditfield(app.UIFigure, 'text');
            app.YourCityEditField.ValueChangedFcn = createCallbackFcn(app, @YourCityEditFieldValueChanged, true);
            app.LegendLabel = uilabel(app.UIFigure);
            app.LegendLabel.Text = 'Legend:';
            app.USCityLabel = uilabel(app.UIFigure);
            app.USCityLabel.Text = 'US City:';
            app.USCityEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = CityComparisons

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end